classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        targetButton    matlab.ui.control.Button
        fixationButton  matlab.ui.control.Button
        startexpButton  matlab.ui.control.Button
        currentState    matlab.ui.control.Label
    end


    properties (Access = private)
        params = getparams();
        trial % Description
        movement % Description
        fixation % fixation 
        preperation % preperation 
        % properties about running experiment 
        curtrial % cur trial 
        curmov % cur movement 
        startT % start timer         
    end

    methods (Access = private)
    
        function waitfor(app)
            
        end
        
        function runTrial(app)
           runMovement(app) 
        end
        function runMovement(app)
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: targetButton
        function targetButtonClicked(app, event)
            
            fprintf('%f %f %f %f \n',app.targetButton.Position );
        end

        % Button pushed function: startexpButton
        function startExp(app, event)
            app.targetButton.Visible = 'off';
            app.fixationButton.Visible = 'on'; 
            app.startexpButton.Visible = 'off';
            for i = 1:app.params.trials% loop on trials
                app.curtrial  = i; 
                runTrial(app,i);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.targetButton = uibutton(app.UIFigure, 'push');
            app.targetButton.ButtonPushedFcn = createCallbackFcn(app, @targetButtonClicked, true);
            app.fixationButton = uibutton(app.UIFigure, 'push');
            app.fixationButton.Text = '';
            app.startexpButton = uibutton(app.UIFigure, 'push');
            app.startexpButton.ButtonPushedFcn = createCallbackFcn(app, @startExp, true);
            app.startexpButton.Text = 'start exp';
            app.currentState = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end